classdef genCircle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        ZvolenmonostiPanel              matlab.ui.container.Panel
        hodnotaTretiLabel               matlab.ui.control.Label
        hodnotaDruhaLabel               matlab.ui.control.Label
        hodnotaPrvniLabel               matlab.ui.control.Label
        nuLabel                         matlab.ui.control.Label
        lambdaLabel                     matlab.ui.control.Label
        pLabel                          matlab.ui.control.Label
        rLabel                          matlab.ui.control.Label
        qLabel                          matlab.ui.control.Label
        ParametryLabel                  matlab.ui.control.Label
        ZvolenastrukturaLabel           matlab.ui.control.Label
        StrukturaLabel                  matlab.ui.control.Label
        SSSZSLQRLabel                   matlab.ui.control.Label
        MetodaLabel                     matlab.ui.control.Label
        UrendlkykolonyvozidelLabel      matlab.ui.control.Label
        OvlivnnvozidloSpinner           matlab.ui.control.Spinner
        OvlivnnvozidloSpinnerLabel      matlab.ui.control.Label
        PoetvozidelSpinner              matlab.ui.control.Spinner
        PoetvozidelLabel                matlab.ui.control.Label
        ZmnapoadovanchhodnotvozidelLabel  matlab.ui.control.Label
        RychlostiSpinner                matlab.ui.control.Spinner
        RychlostiLabel                  matlab.ui.control.Label
        RozestupySpinner                matlab.ui.control.Spinner
        RozestupyLabel                  matlab.ui.control.Label
        NastavitvozidlaButton           matlab.ui.control.Button
        PoruchyaplikovannavozidloLabel  matlab.ui.control.Label
        ZahjitsimulaciButton            matlab.ui.control.Button
        AplikovatporuchuButton          matlab.ui.control.Button
        SymetrickmetodyzenPanel         matlab.ui.container.Panel
        kliknutmnaobrzkyvyberteLabel    matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        strukturaLabel_5                matlab.ui.control.Label
        strukturaLabel_4                matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        SymetrickLQRLabel               matlab.ui.control.Label
        SymetrickstavovzptnvazbaLabel   matlab.ui.control.Label
        strukturaLabel_3                matlab.ui.control.Label
        strukturaLabel_2                matlab.ui.control.Label
        strukturaLabel                  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        SimultorkolonyvozidelLabel      matlab.ui.control.Label
        UkonitButton                    matlab.ui.control.Button
        UrenobecnchparametrzenPanel     matlab.ui.container.Panel
        ParametrmaticeRLabel            matlab.ui.control.Label
        DruhparametrmaticeQLabel        matlab.ui.control.Label
        PrvnparametrmaticeQLabel        matlab.ui.control.Label
        rSpinner                        matlab.ui.control.Spinner
        rSpinnerLabel                   matlab.ui.control.Label
        pSpinner                        matlab.ui.control.Spinner
        pSpinnerLabel                   matlab.ui.control.Label
        qSpinner_2                      matlab.ui.control.Spinner
        qSpinner_2Label                 matlab.ui.control.Label
        PokraujButton                   matlab.ui.control.Button
        NuSpinner                       matlab.ui.control.Spinner
        NuSpinnerLabel                  matlab.ui.control.Label
        LambdySpinner                   matlab.ui.control.Spinner
        LambdySpinnerLabel              matlab.ui.control.Label
        DruhvlastnsloLabel              matlab.ui.control.Label
        PrvnvlastnsloLabel              matlab.ui.control.Label
        VbrvhovchmaticLabel             matlab.ui.control.Label
        VbrvlastnchselLabel             matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        requiredGap = 0 % setpoint of all gaps between vehicles
        requiredSpeed = 0 % setpoint of speeds to keep
        platoonLength
        disturbanceActive = 0
        gapChanged = 0
        speedChanged = 0
        runSim = 0
        rizeni = 0
        param1 = 0
        param2 = 0
        param3 = 0
        
    end

    methods (Access = public)

        function [cars,numberOfCars,radiusCars,lengthCar,centerPoint] = generateCars(app,n,distanceGap,C)
            centerPoint = [1 1];
            radiusCars = 1;

            % --------------------------------- outer circle
            radiusCircle = radiusCars + 0.1;
            coordsStart = centerPoint-radiusCircle;
            transitionPos = [2*radiusCircle 2*radiusCircle];
            pos = [coordsStart, transitionPos];
            rectangle(app.UIAxes, 'Position',pos,'Curvature',[1 1],'EdgeColor','y')
            % --------------------------------- inner circle
            innerRadius = radiusCars-0.1;
            innerStart= centerPoint-innerRadius;
            transitionPosInner = [2*innerRadius 2*innerRadius];
            posInner = [innerStart, transitionPosInner];
            rectangle(app.UIAxes, 'Position',posInner,'Curvature',[1 1], 'EdgeColor','y')
            % ---------------------------------

%             C = ['r','c','m','b','g',[0.4660 0.6740 0.1880],'y',[0.6350 0.0780 0.1840],[0 0.4470 0.7410],[0.9290 0.6940 0.1250]];
%             C = [1 0 0; 0 1 1; 1 0 1; 0 0 1; 0 1 0;0.4660 0.6740 0.1880; 1 1 0; 0.6350 0.0780 0.1840; 0 0.4470 0.7410; 0.9290 0.6940 0.1250];
            
            lengthCar = 0.1;
            centerCar = centerPoint-lengthCar/2;        % car in the center of the circle
            xInit = zeros(1,n);
            yInit = xInit;
            for i = 1:n
                xInit(1,i) = centerCar(1) + radiusCars*cos(-distanceGap*(i-1));
                yInit(1,i) = centerCar(2) + radiusCars*sin(-distanceGap*(i-1));

                cars(i) = rectangle(app.UIAxes, 'Position', [yInit(i) xInit(i) lengthCar lengthCar],'EdgeColor','k','FaceColor', C(i+1,:));
                numberOfCars(i) = text(app.UIAxes, yInit(i)+lengthCar/2, xInit(i)+lengthCar/2, int2str(i),'HorizontalAlignment','center');
            end
        end

        function updateCars(app,n,r,lengthCar,safetyDistance,cars,numberOfCars,circleCenter,distanceGaps,phiPosition)
            xp = zeros(1,n);
            yp = xp;
            centerCar = circleCenter-lengthCar/2;        % car in the center of the circle
            for j = 1:n
                % position update of the cars placed in the circle
                xp(1,j) = centerCar(1) + r*cos(-phiPosition-distanceGaps(j,1)*(n-j)-safetyDistance*(n-j));
                yp(1,j) = centerCar(2) + r*sin(-phiPosition-distanceGaps(j,1)*(n-j)-safetyDistance*(n-j));
                cars(j).Position = [xp(j) yp(j) lengthCar lengthCar];
                numberOfCars(j).Position = [xp(j)+lengthCar/2 yp(j)+lengthCar/2 0];
            end
        end     
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ZahjitsimulaciButton
        function startZahjitsimulaciButtonPushed(app, event)
            % logic of elements
            app.param1
            app.param2
            app.param3
            app.ZahjitsimulaciButton.Enable = false;
%             delete(app.NastavitvozidlaButton);

            app.AplikovatporuchuButton.Enable = true;
            app.OvlivnnvozidloSpinner.Enable = true;
            app.OvlivnnvozidloSpinnerLabel.Enable = true;
            
            % initialization of platoon
            numOfCars = app.PoetvozidelSpinner.Value; % input
            Ts = 0.1; % sampling period of the discrete system
            [A1,B1,C1,D1,numOfStates,~,~,~] = systemGen(numOfCars);
            initConds = zeros(numOfStates,1);
            
            C = [1 0 0; 0 1 1; 1 0 1; 0 0 1; 0 1 0;0.4660 0.6740 0.1880; 1 1 0; 0.6350 0.0780 0.1840; 0 0.4470 0.7410; 0.9290 0.6940 0.1250]; 
            distanceKeeping = 0.2; % safety distance - could be input
            [genCars,genNumberOfCars,genRadius,genLengthCar,genCenter] = generateCars(app,numOfCars,distanceKeeping,C);


%             app.rizeni
            
            % set eigenvalues
            p = app.param1; % can be input
            q = app.param2; % can be input
            r = app.param3;
            eigenvals = [p q r];
            structure = app.rizeni;
            F = symFgen(numOfCars,numOfStates,eigenvals,structure,A1,B1);
            
            %set tracking values
            setPositions = app.RozestupySpinner.Value; % input
            setSpeed = app.RychlostiSpinner.Value; % input
            
            setpointGain = 1;
            if structure == 1
                setpointGain = eigenvals(1)/(eigenvals(1)-1);
            elseif structure == 2  
                setpointGain = eigenvals(2)/(eigenvals(2)-1);
            elseif structure == 3
                if eigenvals(1) == eigenvals(2)
                    setpointGain = (eigenvals(1)+eigenvals(2))/(eigenvals(1)+eigenvals(2)-2);
                else
                    setpointGain = eigenvals(1)/(eigenvals(1)-1);
                end
            elseif structure == 5
                % lqr
                if eigenvals(3) == 1
                    setpointGain = (eigenvals(1))/(eigenvals(1)-1);
                else
                    setpointGain = (eigenvals(1)+1)/eigenvals(3);
                end
            end
            
            correctSpeeds = setSpeed*setpointGain;
            setpoints = zeros(numOfStates,1);
            setpoints(1:2:numOfStates,1) = correctSpeeds;
            setpoints(2:2:numOfStates,1) = setPositions;
            x = initConds;
            sys = ss(A1,B1,C1,D1);
            sysd = c2d(sys,Ts,'zoh');
            Azd = sysd.A;
            Bzd = sysd.B;
            k = 1;
            phi = 0;
            
            a = tic; % start timer
            
            moveConst = 10;
            updateTime = 1/moveConst;
            
            bp = bar(app.UIAxes2, 0:numOfCars, [0; x(2:2:numOfStates); 0], "FaceColor","flat");
            bp.CData = C(1:numOfCars+1,1:3);
            bs = bar(app.UIAxes3, 0:numOfCars, [0; x(1:2:numOfStates)], "FaceColor","flat");
            bs.CData = bp.CData;
            while isvalid(app)
                b = toc(a); % check timer
                if b > updateTime
                    drawnow % update screen every 1/30 seconds
                    a = tic; % reset timer after updating
                    if app.gapChanged == 1
                        setpoints(2:2:numOfStates) = ones(numOfCars-1,1).*(app.requiredGap);
                        app.gapChanged = 0;
                    end

                    if app.speedChanged == 1
                        setpoints(1:2:numOfStates) = ones(numOfCars,1).*(app.requiredSpeed*setpointGain);
                        updateTime = 1/(moveConst + setpoints(1,1)*(moveConst*100));
                        app.speedChanged = 0;
                    end
                    
                    inputDisturbance = zeros(numOfCars,1);
                    if app.disturbanceActive == 1
                        disturbedCar = app.OvlivnnvozidloSpinner.Value;
                        inputDisturbance(disturbedCar,1) = 1*disturbedCar;
                        app.OvlivnnvozidloSpinner.Value = 0;
                        app.disturbanceActive = 0;
                    end

                    distGap = [x(2:2:numOfStates);mean(x(2:2:numOfStates))];
                    
                    updateCars(app,numOfCars,genRadius,genLengthCar,distanceKeeping,genCars,genNumberOfCars,genCenter,distGap/2,phi)
                    
                    bp.YData = [setpoints(2,1); distGap];
                    bs.YData = [setpoints(1,1)/setpointGain; x(1:2:numOfStates)];
                    
                    u = F*(x-setpoints) + inputDisturbance;
                    x = Azd*x + Bzd*u;

                    if k >= 10000
                        assignin("base","u", u);
                        assignin("base","x", x);
                        break;
                    end
                    phi = phi + 0.02;
                    k = k + 1;
                end

            end
        end

        % Button pushed function: UkonitButton
        function UkonitButtonPushed(app, event)
            close all;
            delete(app);
        end

        % Value changed function: RozestupySpinner
        function RozestupySpinnerValueChanged(app, event)
            app.requiredGap = app.RozestupySpinner.Value;
            app.gapChanged = 1;
        end

        % Value changed function: RychlostiSpinner
        function RychlostiSpinnerValueChanged(app, event)
            app.requiredSpeed = app.RychlostiSpinner.Value;
            app.speedChanged = 1;
        end

        % Button pushed function: NastavitvozidlaButton
        function NastavitvozidlaButtonPushed(app, event)
            app.platoonLength = app.PoetvozidelSpinner.Value;
            
            app.PoetvozidelSpinner.Enable = false;
            app.PoetvozidelLabel.Enable = false;
            app.NastavitvozidlaButton.Enable = false;
            
            app.ZahjitsimulaciButton.Enable = true;
            app.RychlostiSpinner.Enable = true;
            app.RozestupySpinner.Enable = true;
            app.RozestupyLabel.Enable = true;
            app.RychlostiLabel.Enable = true;
        end

        % Callback function
        function OvlivnnvozidloEditFieldValueChanged(app, event)

        end

        % Button pushed function: AplikovatporuchuButton
        function AplikovatporuchuButtonPushed(app, event)
            if app.OvlivnnvozidloSpinner.Value > 0
                app.disturbanceActive = 1;
            end
        end

        % Value changed function: OvlivnnvozidloSpinner
        function OvlivnnvozidloSpinnerValueChanged(app, event)
            app.OvlivnnvozidloSpinner.Limits = [0 app.platoonLength];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close all;
            delete(app);
        end

        % Callback function
        function ZastavitButtonPushed(app, event)

        end

        % Image clicked function: Image_3
        function Image_3Clicked(app, event)
            app.UrenobecnchparametrzenPanel.Visible = true;
%             app.Image3.Enable = false;
            app.SymetrickmetodyzenPanel.Enable = 'off';
            
            app.PrvnparametrmaticeQLabel.Enable = false;
            app.DruhparametrmaticeQLabel.Enable = false;
            app.ParametrmaticeRLabel.Enable = false;
            app.pSpinner.Enable = false;
            app.qSpinner_2.Enable = false;
            app.rSpinner.Enable = false;
            app.qSpinner_2Label.Enable = false;
            app.pSpinnerLabel.Enable = false;
            app.rSpinnerLabel.Enable = false;
            app.VbrvhovchmaticLabel.Enable = false;
            
            app.NuSpinner.Enable = false;
            app.NuSpinnerLabel.Enable = false;
            app.DruhvlastnsloLabel.Enable = false;
            app.rizeni = 1;
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            app.UrenobecnchparametrzenPanel.Visible = true;
            app.SymetrickmetodyzenPanel.Enable = 'off';
            app.rizeni = 2;
            
            app.PrvnparametrmaticeQLabel.Enable = false;
            app.DruhparametrmaticeQLabel.Enable = false;
            app.ParametrmaticeRLabel.Enable = false;
            app.pSpinner.Enable = false;
            app.qSpinner_2.Enable = false;
            app.rSpinner.Enable = false;
            app.qSpinner_2Label.Enable = false;
            app.pSpinnerLabel.Enable = false;
            app.rSpinnerLabel.Enable = false;
            app.VbrvhovchmaticLabel.Enable = false;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.UrenobecnchparametrzenPanel.Visible = true;
            app.SymetrickmetodyzenPanel.Enable = 'off';
            app.rizeni = 3;
            
            app.PrvnparametrmaticeQLabel.Enable = false;
            app.DruhparametrmaticeQLabel.Enable = false;
            app.ParametrmaticeRLabel.Enable = false;
            app.pSpinner.Enable = false;
            app.qSpinner_2.Enable = false;
            app.rSpinner.Enable = false;
            app.qSpinner_2Label.Enable = false;
            app.pSpinnerLabel.Enable = false;
            app.rSpinnerLabel.Enable = false;
            app.VbrvhovchmaticLabel.Enable = false;
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            app.UrenobecnchparametrzenPanel.Visible = true;
            app.SymetrickmetodyzenPanel.Enable = 'off';
            app.rizeni = 4;
            
            app.VbrvlastnchselLabel.Enable = false;
            app.LambdySpinner.Enable = false;
            app.LambdySpinnerLabel.Enable = false;
            app.NuSpinner.Enable = false;
            app.NuSpinnerLabel.Enable = false;
            app.PrvnvlastnsloLabel.Enable = false;
            app.DruhvlastnsloLabel.Enable = false;
            
            app.DruhparametrmaticeQLabel.Enable = false;
            app.pSpinner.Enable = false;
            app.pSpinnerLabel.Enable = false;
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
%             delete(app.SymetrickmetodyzenPanel);
            app.UrenobecnchparametrzenPanel.Visible = true;
            app.SymetrickmetodyzenPanel.Enable = 'off';
            app.rizeni = 5;
            
            app.VbrvlastnchselLabel.Enable = false;
            app.LambdySpinner.Enable = false;
            app.LambdySpinnerLabel.Enable = false;
            app.NuSpinner.Enable = false;
            app.NuSpinnerLabel.Enable = false;
            app.PrvnvlastnsloLabel.Enable = false;
            app.DruhvlastnsloLabel.Enable = false;
        end

        % Button pushed function: PokraujButton
        function PokraujButtonPushed(app, event)
            if app.rizeni < 4
                app.param1 = app.LambdySpinner.Value;
                app.SSSZSLQRLabel.Text = "S-SZV";
                delete(app.qLabel);
                delete(app.pLabel);
                delete(app.rLabel);
                delete(app.hodnotaTretiLabel);
                app.ZvolenastrukturaLabel.Text = "1. struktura";
                if app.param1 == 1
                    app.param1 = 1.1;
                end
                app.hodnotaPrvniLabel.Text = num2str(app.param1);
                
                if app.rizeni > 1
                    app.ZvolenastrukturaLabel.Text = "2. struktura";
                    app.param2 = app.NuSpinner.Value;              
                    if app.param2 == 1
                        app.param2 = 1.1;
                    end
                    app.hodnotaDruhaLabel.Text = num2str(app.param2);
                    if app.rizeni == 3
                        app.ZvolenastrukturaLabel.Text = "3. struktura";
                    end
                else
                    delete(app.hodnotaDruhaLabel);
                    delete(app.nuLabel);
                end
            else
                delete(app.lambdaLabel);
                delete(app.nuLabel);
                app.ZvolenastrukturaLabel.Text = "1. struktura";
                app.SSSZSLQRLabel.Text = "S-LQR";
                % LQR method
                app.param3 = app.rSpinner.Value;
                app.param2 = app.qSpinner_2.Value ;
                
                app.hodnotaDruhaLabel.Text = num2str(app.param2);
                app.hodnotaPrvniLabel.Text = num2str(app.param3);
                if app.rizeni == 5
                    app.ZvolenastrukturaLabel.Text = "2. struktura";
                    % if all states are used
                    app.param1 = app.pSpinner.Value;
                    if app.param1 == 1
                        app.param1 = 1.1;
                    end
                    app.hodnotaTretiLabel.Text = num2str(app.param1);
                else
                    delete(app.hodnotaTretiLabel);
                    delete(app.pLabel);
                end
            end
            
            app.UIAxes.Visible = true;
            app.UIAxes2.Visible = true; 
            app.UIAxes3.Visible = true;
            app.Panel.Visible = true;
      
            delete(app.SymetrickmetodyzenPanel);
            delete(app.UrenobecnchparametrzenPanel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Udržování rozestupů')
            xlabel(app.UIAxes2, 'Vozidlo')
            ylabel(app.UIAxes2, 'Rozestup')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YLim = [0 3];
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Udržování rychlostí')
            xlabel(app.UIAxes3, 'Vozidlo')
            ylabel(app.UIAxes3, 'Rychlosti')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.YLim = [0 10];
            app.UIAxes3.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UrenobecnchparametrzenPanel = uipanel(app.UIFigure);
            app.UrenobecnchparametrzenPanel.Title = 'Určení obecných parametrů řízení';
            app.UrenobecnchparametrzenPanel.Visible = 'off';
            app.VbrvlastnchselLabel = uilabel(app.UrenobecnchparametrzenPanel);
            app.VbrvlastnchselLabel.Text = 'Výběr vlastních čísel';
            app.VbrvhovchmaticLabel = uilabel(app.UrenobecnchparametrzenPanel);
            app.VbrvhovchmaticLabel.Text = 'Výběr váhových matic';
            app.PrvnvlastnsloLabel = uilabel(app.UrenobecnchparametrzenPanel);
            app.PrvnvlastnsloLabel.Text = 'První vlastní číslo';
            app.DruhvlastnsloLabel = uilabel(app.UrenobecnchparametrzenPanel);
            app.DruhvlastnsloLabel.Text = 'Druhý vlastní číslo';
            app.LambdySpinnerLabel = uilabel(app.UrenobecnchparametrzenPanel);
            app.LambdySpinnerLabel.Text = 'Lambdy';
            app.LambdySpinner = uispinner(app.UrenobecnchparametrzenPanel);
            app.LambdySpinner.Step = 0.5;
            app.LambdySpinner.Limits = [0.5 Inf];
            app.LambdySpinner.Value = 0.5;
            app.NuSpinnerLabel = uilabel(app.UrenobecnchparametrzenPanel);
            app.NuSpinnerLabel.Text = 'Nu';
            app.NuSpinner = uispinner(app.UrenobecnchparametrzenPanel);
            app.NuSpinner.Step = 0.5;
            app.NuSpinner.Limits = [0.5 Inf];
            app.NuSpinner.Value = 0.5;
            app.PokraujButton = uibutton(app.UrenobecnchparametrzenPanel, 'push');
            app.PokraujButton.ButtonPushedFcn = createCallbackFcn(app, @PokraujButtonPushed, true);
            app.PokraujButton.Text = 'Pokračuj';
            app.qSpinner_2Label = uilabel(app.UrenobecnchparametrzenPanel);
            app.qSpinner_2Label.Text = 'q';
            app.qSpinner_2 = uispinner(app.UrenobecnchparametrzenPanel);
            app.qSpinner_2.Limits = [0.1 Inf];
            app.qSpinner_2.Value = 1;
            app.pSpinnerLabel = uilabel(app.UrenobecnchparametrzenPanel);
            app.pSpinnerLabel.Text = 'p';
            app.pSpinner = uispinner(app.UrenobecnchparametrzenPanel);
            app.pSpinner.Limits = [0.1 Inf];
            app.pSpinner.Value = 1;
            app.rSpinnerLabel = uilabel(app.UrenobecnchparametrzenPanel);
            app.rSpinnerLabel.Text = 'r';
            app.rSpinner = uispinner(app.UrenobecnchparametrzenPanel);
            app.rSpinner.Limits = [0.1 Inf];
            app.rSpinner.Value = 1;
            app.PrvnparametrmaticeQLabel = uilabel(app.UrenobecnchparametrzenPanel);
            app.PrvnparametrmaticeQLabel.Text = 'První parametr matice Q';
            app.DruhparametrmaticeQLabel = uilabel(app.UrenobecnchparametrzenPanel);
            app.DruhparametrmaticeQLabel.Text = 'Druhý parametr matice Q';
            app.ParametrmaticeRLabel = uilabel(app.UrenobecnchparametrzenPanel);
            app.ParametrmaticeRLabel.Text = 'Parametr matice R';
            app.UkonitButton = uibutton(app.UIFigure, 'push');
            app.UkonitButton.ButtonPushedFcn = createCallbackFcn(app, @UkonitButtonPushed, true);
            app.UkonitButton.Text = 'Ukončit';
            app.SimultorkolonyvozidelLabel = uilabel(app.UIFigure);
            app.SimultorkolonyvozidelLabel.Text = 'Simulátor kolony vozidel';
            app.SymetrickmetodyzenPanel = uipanel(app.UIFigure);
            app.SymetrickmetodyzenPanel.Title = 'Symetrické metody řízení';
            app.Image_3 = uiimage(app.SymetrickmetodyzenPanel);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @Image_3Clicked, true);
            app.Image_3.ImageSource = 'struktura1.png';
            app.Image_2 = uiimage(app.SymetrickmetodyzenPanel);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = 'struktura2.png';
            app.Image = uiimage(app.SymetrickmetodyzenPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'struktura3.png';
            app.strukturaLabel = uilabel(app.SymetrickmetodyzenPanel);
            app.strukturaLabel.Text = '1. struktura';
            app.strukturaLabel_2 = uilabel(app.SymetrickmetodyzenPanel);
            app.strukturaLabel_2.Text = '2. struktura';
            app.strukturaLabel_3 = uilabel(app.SymetrickmetodyzenPanel);
            app.strukturaLabel_3.Text = '3. struktura';
            app.SymetrickstavovzptnvazbaLabel = uilabel(app.SymetrickmetodyzenPanel);
            app.SymetrickstavovzptnvazbaLabel.Text = 'Symetrická stavová zpětná vazba';
            app.SymetrickLQRLabel = uilabel(app.SymetrickmetodyzenPanel);
            app.SymetrickLQRLabel.Text = 'Symetrické LQR';
            app.Image2 = uiimage(app.SymetrickmetodyzenPanel);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'lqrKomplet.png';
            app.strukturaLabel_4 = uilabel(app.SymetrickmetodyzenPanel);
            app.strukturaLabel_4.Text = '1. struktura';
            app.strukturaLabel_5 = uilabel(app.SymetrickmetodyzenPanel);
            app.strukturaLabel_5.Text = '2. struktura';
            app.Image3 = uiimage(app.SymetrickmetodyzenPanel);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = 'lqrRozestupy.png';
            app.kliknutmnaobrzkyvyberteLabel = uilabel(app.SymetrickmetodyzenPanel);
            app.kliknutmnaobrzkyvyberteLabel.Text = '- kliknutím na obrázky vyberte';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.AplikovatporuchuButton = uibutton(app.Panel, 'push');
            app.AplikovatporuchuButton.ButtonPushedFcn = createCallbackFcn(app, @AplikovatporuchuButtonPushed, true);
            app.AplikovatporuchuButton.Enable = 'off';
            app.AplikovatporuchuButton.Text = 'Aplikovat poruchu';
            app.ZahjitsimulaciButton = uibutton(app.Panel, 'push');
            app.ZahjitsimulaciButton.ButtonPushedFcn = createCallbackFcn(app, @startZahjitsimulaciButtonPushed, true);
            app.ZahjitsimulaciButton.Enable = 'off';
            app.ZahjitsimulaciButton.Text = 'Zahájit simulaci';
            app.PoruchyaplikovannavozidloLabel = uilabel(app.Panel);
            app.PoruchyaplikovannavozidloLabel.Text = 'Poruchy aplikované na vozidlo';
            app.NastavitvozidlaButton = uibutton(app.Panel, 'push');
            app.NastavitvozidlaButton.ButtonPushedFcn = createCallbackFcn(app, @NastavitvozidlaButtonPushed, true);
            app.NastavitvozidlaButton.Text = 'Nastavit vozidla';
            app.RozestupyLabel = uilabel(app.Panel);
            app.RozestupyLabel.Enable = 'off';
            app.RozestupyLabel.Text = 'Rozestupy:';
            app.RozestupySpinner = uispinner(app.Panel);
            app.RozestupySpinner.Step = 0.5;
            app.RozestupySpinner.Limits = [0 2.5];
            app.RozestupySpinner.ValueChangedFcn = createCallbackFcn(app, @RozestupySpinnerValueChanged, true);
            app.RozestupySpinner.Enable = 'off';
            app.RychlostiLabel = uilabel(app.Panel);
            app.RychlostiLabel.Enable = 'off';
            app.RychlostiLabel.Text = 'Rychlosti:';
            app.RychlostiSpinner = uispinner(app.Panel);
            app.RychlostiSpinner.Step = 0.5;
            app.RychlostiSpinner.Limits = [0 10];
            app.RychlostiSpinner.ValueChangedFcn = createCallbackFcn(app, @RychlostiSpinnerValueChanged, true);
            app.RychlostiSpinner.Enable = 'off';
            app.ZmnapoadovanchhodnotvozidelLabel = uilabel(app.Panel);
            app.ZmnapoadovanchhodnotvozidelLabel.Text = 'Změna požadovaných hodnot vozidel';
            app.PoetvozidelLabel = uilabel(app.Panel);
            app.PoetvozidelLabel.Text = 'Počet vozidel:';
            app.PoetvozidelSpinner = uispinner(app.Panel);
            app.PoetvozidelSpinner.Limits = [2 6];
            app.PoetvozidelSpinner.Value = 2;
            app.OvlivnnvozidloSpinnerLabel = uilabel(app.Panel);
            app.OvlivnnvozidloSpinnerLabel.Enable = 'off';
            app.OvlivnnvozidloSpinnerLabel.Text = 'Ovlivněné vozidlo:';
            app.OvlivnnvozidloSpinner = uispinner(app.Panel);
            app.OvlivnnvozidloSpinner.Limits = [0 Inf];
            app.OvlivnnvozidloSpinner.ValueChangedFcn = createCallbackFcn(app, @OvlivnnvozidloSpinnerValueChanged, true);
            app.OvlivnnvozidloSpinner.Enable = 'off';
            app.UrendlkykolonyvozidelLabel = uilabel(app.Panel);
            app.UrendlkykolonyvozidelLabel.Text = 'Určení délky kolony vozidel';
            app.ZvolenmonostiPanel = uipanel(app.Panel);
            app.ZvolenmonostiPanel.Title = 'Zvolené možnosti';
            app.MetodaLabel = uilabel(app.ZvolenmonostiPanel);
            app.MetodaLabel.Text = 'Metoda:';
            app.SSSZSLQRLabel = uilabel(app.ZvolenmonostiPanel);
            app.SSSZSLQRLabel.Text = 'SSSZ/SLQR';
            app.StrukturaLabel = uilabel(app.ZvolenmonostiPanel);
            app.StrukturaLabel.Text = 'Struktura:';
            app.ZvolenastrukturaLabel = uilabel(app.ZvolenmonostiPanel);
            app.ZvolenastrukturaLabel.Text = 'Zvolena struktura';
            app.ParametryLabel = uilabel(app.ZvolenmonostiPanel);
            app.ParametryLabel.Text = 'Parametry:';
            app.qLabel = uilabel(app.ZvolenmonostiPanel);
            app.qLabel.Text = 'q =';
            app.rLabel = uilabel(app.ZvolenmonostiPanel);
            app.rLabel.Text = 'r =';
            app.pLabel = uilabel(app.ZvolenmonostiPanel);
            app.pLabel.Text = 'p =';
            app.lambdaLabel = uilabel(app.ZvolenmonostiPanel);
            app.lambdaLabel.Text = 'lambda =';
            app.nuLabel = uilabel(app.ZvolenmonostiPanel);
            app.nuLabel.Text = 'nu =';
            app.hodnotaPrvniLabel = uilabel(app.ZvolenmonostiPanel);
            app.hodnotaPrvniLabel.Text = 'hodnotaPrvni';
            app.hodnotaDruhaLabel = uilabel(app.ZvolenmonostiPanel);
            app.hodnotaDruhaLabel.Text = 'hodnotaDruha';
            app.hodnotaTretiLabel = uilabel(app.ZvolenmonostiPanel);
            app.hodnotaTretiLabel.Text = 'hodnotaTreti';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = genCircle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end