classdef setpointy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        VozidloEditField               matlab.ui.control.NumericEditField
        VozidloEditFieldLabel          matlab.ui.control.Label
        AmplitudaSpinner               matlab.ui.control.Spinner
        AmplitudaSpinnerLabel          matlab.ui.control.Label
        AplikovnporuchyLabel           matlab.ui.control.Label
        NastavButton                   matlab.ui.control.Button
        UkonitButton                   matlab.ui.control.Button
        RychlostiSpinner               matlab.ui.control.Spinner
        RychlostiSpinnerLabel          matlab.ui.control.Label
        NastavenpoadovanchhodnotLabel  matlab.ui.control.Label
        RozestupySpinner               matlab.ui.control.Spinner
        RozestupySpinnerLabel          matlab.ui.control.Label
        PocetvozidelEditField          matlab.ui.control.NumericEditField
        PocetvozidelEditFieldLabel     matlab.ui.control.Label
        VykresliButton                 matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        cas %max cas simulace
    end

    methods (Access = private)

        function [auto,cisla] = generuj(app,n,mezera)
            poloha = zeros(1,n);
            for i=1:n
%                 poloha(i) = i+mezera*(i-1);
                poloha(i) = i;
            end
            %stred 1;1
            pos = [0 0 1 1]; 
            rectangle(app.UIAxes, 'Position',pos,'Curvature',[1 1])     
%             x = r*cos(uhel)
%             y = r*sin(uhel)
%             set(app.UIAxes,'axis', [])
            r = 0.5;
            C = {'c','m','b','r','g',[0.4660 0.6740 0.1880],'y',[0.6350 0.0780 0.1840],[0 0.4470 0.7410],[0.9290 0.6940 0.1250]};
            for i=1:n
%                 r = 0.5;
                x_c(i) = r*cos(40*i*pi/180)+1
                y_c(i) = r*sin(40*i*pi/180)+1
                auto(i) = rectangle(app.UIAxes, 'Position', [x_c(i) y_c(i) 0.1 0.1],'EdgeColor','k','FaceColor', C{i},'Curvature',[1 1]);
%                 auto(i) = rectangle(app.UIAxes, 'Position', [i/10 0 0.1 0.1],'EdgeColor','k','FaceColor', C{i});
%                 primka(i) = rectangle(app.UIAxes, 'Position', [poloha(n+1-i)+1 1/2 mezera 1/100],'EdgeColor','r');
                cisla(i) = text(app.UIAxes, x_c(i)+0.1/2,y_c(i)+0.1/2, int2str(i),'HorizontalAlignment','center');
%                 r(i) = sqrt(x^2 + y^2)
%                 uhel(i) = atan2(y,x)
                
            end           
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VykresliButton
        function VykresliButtonPushed(app, event)
%             app.NastavenpoadovanchhodnotLabel.Visible = 'on';
%             app.RychlostiSpinner.Visible = 'on';
%             app.RozestupySpinner.Visible = 'on';
%             app.RychlostiSpinnerLabel.Visible = 'on';
%             app.RozestupySpinnerLabel.Visible = 'on';
            app.AplikovnporuchyLabel.Visible = 'on';
            app.VozidloEditField.Visible = 'on';
            app.AmplitudaSpinner.Visible = 'on';
            app.VozidloEditFieldLabel.Visible = 'on';
            app.AmplitudaSpinnerLabel.Visible = 'on';
            mezera = (app.RozestupySpinner.Value);
%             N = str2double(app.PocetvozidelEditField.Value);
            N = (app.PocetvozidelEditField.Value);
            disp(mezera)
            [auto,cisla] = generuj(app,N,mezera);

            [A1,B1,C1,D1,pocet_stavu] = systemGen(N);
            syms lambda ni
            [Fpocet] = Fgen(N,pocet_stavu,lambda, ni);
            K1 = double(subs(Fpocet, [lambda, ni], [1/2, 1]))
            porucha1 = B1;
            Az1 = A1 + B1*K1
        
            sys = ss(Az1,porucha1,C1,D1);
            sysd = c2d(sys,0.1,'zoh');
            setps = B1*K1*C1;
            Bp = sysd.B;
            
            sys = ss(Az1,setps,C1,D1);
            sysd = c2d(sys,0.1,'zoh');
            Azd = sysd.A;
            Bset = sysd.B;
            max = 500;
            x = zeros(pocet_stavu,max);
            u = zeros(pocet,max);
            k = 1;

            while isvalid(app)
                chyba = str2double(app.VozidloEditField.Value);
                if chyba == 0
                    vstup = 0;                   
                    chyba = 1;
                else
                    vstup = str2double(app.AmplitudaSpinner.Value);
%                     app.ZadejvelikostporuchyEditField.Enable = 'on';
                end
                                   
                u(chyba,k) = vstup;
                
                x(:,k+1) = Azd*x(:,k) + Bp*u(:,k) - Bset*u(:,k);
                y(:,k) = C1*x(:,k);
                k = k + 1;
            end
        end

        % Callback function
        function RozestupySpinnerValueChanged(app, event)

        end

        % Button pushed function: NastavButton
        function NastavButtonPushed(app, event)
            app.NastavButton.Enable = 'off';
            app.PocetvozidelEditField.Enable = 'off';
            app.RychlostiSpinner.Visible = 'on';
            app.RozestupySpinner.Visible = 'on';
            app.RychlostiSpinnerLabel.Visible = 'on';
            app.RozestupySpinnerLabel.Visible = 'on';
            app.VykresliButton.Visible = 'on';
        end

        % Callback function
        function RozestupySpinnerValueChanged2(app, event)

        end

        % Callback function
        function RozestupySpinnerValueChanging(app, event)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.VykresliButton = uibutton(app.LeftPanel, 'push');
            app.VykresliButton.ButtonPushedFcn = createCallbackFcn(app, @VykresliButtonPushed, true);
            app.VykresliButton.Visible = 'off';
            app.VykresliButton.Text = 'Vykresli';
            app.PocetvozidelEditFieldLabel = uilabel(app.LeftPanel);
            app.PocetvozidelEditFieldLabel.Text = 'Pocet vozidel';
            app.PocetvozidelEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RozestupySpinnerLabel = uilabel(app.LeftPanel);
            app.RozestupySpinnerLabel.Visible = 'off';
            app.RozestupySpinnerLabel.Text = 'Rozestupy';
            app.RozestupySpinner = uispinner(app.LeftPanel);
            app.RozestupySpinner.Step = 0.5;
            app.RozestupySpinner.Visible = 'off';
            app.NastavenpoadovanchhodnotLabel = uilabel(app.LeftPanel);
            app.NastavenpoadovanchhodnotLabel.Visible = 'off';
            app.NastavenpoadovanchhodnotLabel.Text = 'Nastavení požadovaných hodnot';
            app.RychlostiSpinnerLabel = uilabel(app.LeftPanel);
            app.RychlostiSpinnerLabel.Visible = 'off';
            app.RychlostiSpinnerLabel.Text = 'Rychlosti';
            app.RychlostiSpinner = uispinner(app.LeftPanel);
            app.RychlostiSpinner.Step = 0.5;
            app.RychlostiSpinner.Visible = 'off';
            app.UkonitButton = uibutton(app.LeftPanel, 'push');
            app.UkonitButton.Text = 'Ukončit';
            app.NastavButton = uibutton(app.LeftPanel, 'push');
            app.NastavButton.ButtonPushedFcn = createCallbackFcn(app, @NastavButtonPushed, true);
            app.NastavButton.Text = 'Nastav';
            app.AplikovnporuchyLabel = uilabel(app.LeftPanel);
            app.AplikovnporuchyLabel.Visible = 'off';
            app.AplikovnporuchyLabel.Text = 'Aplikování poruchy';
            app.AmplitudaSpinnerLabel = uilabel(app.LeftPanel);
            app.AmplitudaSpinnerLabel.Visible = 'off';
            app.AmplitudaSpinnerLabel.Text = 'Amplituda';
            app.AmplitudaSpinner = uispinner(app.LeftPanel);
            app.AmplitudaSpinner.Visible = 'off';
            app.VozidloEditFieldLabel = uilabel(app.LeftPanel);
            app.VozidloEditFieldLabel.Visible = 'off';
            app.VozidloEditFieldLabel.Text = 'Vozidlo';
            app.VozidloEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VozidloEditField.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setpointy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end